classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CalculatePlotButton        matlab.ui.control.Button
        ComponentsLabel            matlab.ui.control.Label
        CalculatedparametersLabel  matlab.ui.control.Label
        UITable2                   matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
        FitButton                  matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        H
        M
        dMdH
        HdMdH
        Hhat
        Mhat
        dMdHhat
        HdMdHhat
        Hcr
        mcr
        a
        alphaMs
        Ms
        alpha
        dimensionless_alphaMs
        Hk
    end
    
    methods (Access = private)
        
        function results = plot(app)
            plotter = Plotter(app.H, app.M, app.dMdH, app.HdMdH, app.Hhat, app.Mhat, app.dMdHhat, app.HdMdHhat, app.Hcr);
            plotter.plot_M(app.UIAxes);
            plotter.plot_dMdH(app.UIAxes2);
            plotter.plot_HdMdH(app.UIAxes3);
            t = table([app.a(1), app.a(2); app.alphaMs(1), app.alphaMs(2); app.Ms(1), app.Ms(2); app.alpha(1), app.alpha(2); app.dimensionless_alphaMs(1), app.dimensionless_alphaMs(2); app.Hk(1), app.Hk(2)], 'RowNames', {'a'; 'αMs'; 'Ms'; 'α'; 'αMs/(3a)'; 'Hk'});
            app.UITable.Data = t{:,:};
            app.UITable.RowName = t.Properties.RowNames;

            app.UITable2.Data(1) = app.Hcr(1);
            app.UITable2.Data(3) = app.Hcr(2);
            app.UITable2.Data(2) = app.mcr(1);
            app.UITable2.Data(4) = app.mcr(2);
            app.UITable2.Data(5) = (app.Hcr(1) + app.Hcr(2))/2;
        end
        
        function results = calculate_parameters(app)
            N = 100;

            utils = Utils();

            [app.H, app.M] = Parser('data/MvsH - (JNEX-900, AIP advances).csv').get_data_csv;

            app.dMdH = transpose(gradient(app.M(:)) ./ gradient(app.H(:)));

            app.HdMdH = app.H.*app.dMdH;

            [HTip, MTip] = utils.find_tip(app.H, app.M);

            magnetic_parameters = MagneticParameters(app.H, app.M, app.Hcr, app.mcr);
            app.a = magnetic_parameters.get_a();
            app.alphaMs = magnetic_parameters.get_alphaMs(app.a);
            app.Ms = magnetic_parameters.get_Ms(app.a, app.alphaMs);
            app.alpha = magnetic_parameters.get_alpha(app.alphaMs, app.Ms);
            app.dimensionless_alphaMs = magnetic_parameters.get_dimensionless_alphaMs(app.alphaMs, app.a);
            app.Hk = magnetic_parameters.get_Hk(app.alphaMs, app.a);

            app.Hhat = logspace(log10(app.H(2)),log10(HTip),N);
            app.Mhat = get_Mhat(app.Hhat, app.a, app.alphaMs, app.Ms);
            app.dMdHhat = get_dMdHhat(app.Hhat, app.alpha, app.Ms, app.a, app.alphaMs);
            app.HdMdHhat = get_HdMdHhat(app.Hhat, app.dMdHhat);
        end
        
        function results = fit_parameters(app)
            [app.Hcr, app.mcr] = fit(app.H, app.M, cat(2, app.Hcr, app.mcr));
        end
        
        function results = update_components(app)
            app.Hcr(1) = app.UITable2.Data(1);
            app.Hcr(2) = app.UITable2.Data(3);
            app.mcr(1) = app.UITable2.Data(2);
            app.mcr(2) = app.UITable2.Data(4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = table([10; 0.5; 8000; 0.5; 4005], 'RowNames', {'Hcr1'; 'mcr1'; 'Hcr2'; 'mcr2'; 'Hx'});
            app.UITable2.Data = t{:,:};
            app.UITable2.RowName = t.Properties.RowNames;

            %t = table({' '; ' '; ' '; ' '; ' '; ' '}, 'RowNames', {'a'; 'αMs'; 'Ms'; 'α'; 'αMs/(3a)'; 'Hk'});
            %app.UITable.Data = t{:,:};
            %app.UITable.RowName = t.Properties.RowNames;


            update_components(app)
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            update_components(app)
            fit_parameters(app)
            calculate_parameters(app)
            plot(app)
        end

        % Button pushed function: CalculatePlotButton
        function CalculatePlotButtonPushed(app, event)
            update_components(app)
            calculate_parameters(app)
            plot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'H (A/m)')
            ylabel(app.UIAxes, 'M (A/m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'H (A/m)')
            ylabel(app.UIAxes2, '∂M/∂H')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'H (A/m)')
            ylabel(app.UIAxes3, 'H ∂M/∂H (A/m)')
            zlabel(app.UIAxes3, 'Z')
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Component 1'; 'Component 2'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Seed'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.CalculatedparametersLabel = uilabel(app.UIFigure);
            app.CalculatedparametersLabel.Text = 'Calculated parameters';
            app.ComponentsLabel = uilabel(app.UIFigure);
            app.ComponentsLabel.Text = 'Components';
            app.CalculatePlotButton = uibutton(app.UIFigure, 'push');
            app.CalculatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePlotButtonPushed, true);
            app.CalculatePlotButton.Text = 'Calculate & Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end